classdef PowerCalculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CityDropDownLabel               matlab.ui.control.Label
        CityDropDown                    matlab.ui.control.DropDown
        LocationTypeDropDownLabel       matlab.ui.control.Label
        LocationTypeDropDown            matlab.ui.control.DropDown
        calculate                       matlab.ui.control.Button
        TurbineDropDownLabel            matlab.ui.control.Label
        TurbineDropDown                 matlab.ui.control.DropDown
        HeightDropDownLabel             matlab.ui.control.Label
        HeightDropDown                  matlab.ui.control.DropDown
        MonthlyEnergy                   matlab.ui.control.UIAxes
        PowerNEST20Label                matlab.ui.control.Label
        BuildingLengthEditFieldLabel    matlab.ui.control.Label
        BuildingLengthEditField         matlab.ui.control.NumericEditField
        TurbineOrientationDropDownLabel  matlab.ui.control.Label
        TurbineOrientationDropDown      matlab.ui.control.DropDown
        IdealTurbineOrientationEditFieldLabel  matlab.ui.control.Label
        IdealTurbineOrientationEditField  matlab.ui.control.EditField
        BuildingDepthEditField_2Label   matlab.ui.control.Label
        BuildingDepthEditField_2        matlab.ui.control.NumericEditField
        NumberofTurbinesEditFieldLabel  matlab.ui.control.Label
        NumberofTurbinesEditField       matlab.ui.control.NumericEditField
        WindComponentLabel              matlab.ui.control.Label
        SolarComponentLabel             matlab.ui.control.Label
        PossibleSideEdgesDropDownLabel  matlab.ui.control.Label
        PossibleSideEdgesDropDown       matlab.ui.control.DropDown
        FrontandBackEdgesDropDownLabel  matlab.ui.control.Label
        FrontandBackEdgesDropDown       matlab.ui.control.DropDown
        NearestCityDropDownLabel        matlab.ui.control.Label
        NearestCityDropDown             matlab.ui.control.DropDown
        OutputsTextAreaLabel            matlab.ui.control.Label
        OutputsTextArea                 matlab.ui.control.TextArea
        SystemConfigurationTextArea     matlab.ui.control.TextArea
        SystemConfigurationLabel        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: calculate
        function calculatePushed(app, event)
            % Get city name
            city_name = {app.CityDropDown.Value};
            
            %Get height
            height = str2double(app.HeightDropDown.Value)+2.15;
            
            % Get roughness. Do a switch case for the values.
            rough_str = app.LocationTypeDropDown.Value;
            switch rough_str
                case 'Coastal Open'
                    roughness =    0.0024;
                case 'Coastal Urban'
                    roughness =    0.03;
                case 'Airport'
                    roughness =    0.055;
                case 'Inland Open'
                    roughness =    0.1;
                case 'Inland Urban'
                    roughness =    0.2;
                case 'Large Cities'
                    roughness =    0.6;
                otherwise
                    roughness =    0.1;
            end
            
            %Get turbine and assign corresponding power curve
            turbine = app.TurbineDropDown.Value;
            switch turbine
                case 'Aeolos 3kW'
                    power_curve=[0 0 0 0 0.103529 0.184973 0.31691 0.509465 0.80303 1.217808 1.723494 2.279661 2.91666 3.513256 3.624654 3.624654 3.624654 3.624654 3.624654];
                case 'HiVAWT 3kW'
                    power_curve=[0 0 0 0.0107 0.081 0.23 0.406 0.658 0.99 1.38 1.878 2.488 3.007 3.093 3.093 3.093 3.093 3.093 3.093];
            end
            
            %Get Acceleration
            acceleration = 1.2;
            
            %Turbine Orientation
            %'N','NNE','NE','ENE','E','ESE','SE','SSE','S','NNW','NW','WNW','W','WSW','SW','SSW'
            turbine_orientation = app.TurbineOrientationDropDown.Value;
            switch turbine_orientation
                case 'N'
                    orientation_id=1;
                case 'NNE'
                    orientation_id=2;
                case 'NE'
                    orientation_id=3;
                case 'ENE'
                    orientation_id=4;
                case 'E'
                    orientation_id=5;
                case 'ESE'
                    orientation_id=6;
                case 'SE'
                    orientation_id=7;
                case 'SSE'
                    orientation_id=8;
                case 'S'
                    orientation_id=9;
                case 'NNW'
                    orientation_id=10;
                case 'NW'
                    orientation_id=11;
                case 'WNW'
                    orientation_id=12;
                case 'W'
                    orientation_id=13;
                case 'WSW'
                    orientation_id=14;
                case 'SW'
                    orientation_id=15;
                case 'SSW'
                    orientation_id=16;
            end
                    
            % Building Length
            blength = app.BuildingLengthEditField.Value;
            % Building Width
            bwidth = app.BuildingDepthEditField_2.Value;
            
            %Get data of city
            city_wind_data = get_wind_data(city_name);
            
            %Calculate. Call main_calc function
            [ monthly_wind_energy_dist,direction_max_yearly_energy,angle_max_yearly_energy,max_yearly_energy,success ] = MAIN_CALC( city_wind_data,height,roughness,orientation_id,power_curve,acceleration);
            app.IdealTurbineOrientationEditField.Value=direction_max_yearly_energy{1};
            max_yearly_energy = app.NumberofTurbinesEditField.Value*max_yearly_energy;
            n_turbines = app.NumberofTurbinesEditField.Value;
            yearly_energy=n_turbines*sum(monthly_wind_energy_dist);


            %%%%%%%%%%%%%%%%%%%%%%%%%%% Solar Energy Component %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            solar_city_name = app.NearestCityDropDown.Value;
            side_edges = str2double(app.PossibleSideEdgesDropDown.Value);
            fb_edges = str2double(app.FrontandBackEdgesDropDown.Value);
            
            [monthly_solar_energy,n_panels]=calculate_solar_energy(solar_city_name,side_edges,fb_edges,blength,bwidth);
            
            %% Total Energy
            annual_wind=yearly_energy;
            annual_solar=sum(monthly_solar_energy);
            annual_total=annual_wind+annual_solar;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%% Plot %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if(success == 1)
                app.OutputsTextArea.Value = sprintf('Annual wind energy  is %d kWh. \nAnnual solar energy is %d kWh. \nTotal annual energy is %d kWh.',round(annual_wind),round(annual_solar),round(annual_total));    
                
                %Plot bar plot of months vs energy
                months=1:12;
                cumulative_energy_dist=[monthly_wind_energy_dist; monthly_solar_energy]';
                bar(app.MonthlyEnergy,months,cumulative_energy_dist,'stacked');
                legend(app.MonthlyEnergy,{'Wind','Solar'},'Location','Best');
            end
            
            %Write system configuration
            app.SystemConfigurationTextArea.Value = sprintf('Installed Wind Capacity = %.2f kWp. \nInstalled Solar Capacity = %.2f kWp.',n_turbines*3.6,n_panels*0.285)           
            
        end

        % Value changed function: BuildingLengthEditField
        function calcNumberofTurbines(app, event)
            blength = app.BuildingLengthEditField.Value;
            value = floor(blength/7.2);
            app.NumberofTurbinesEditField.Value = value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.CityDropDownLabel = uilabel(app.UIFigure);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.UIFigure);
            app.CityDropDown.Items = {'Aruba', 'Rotterdam', 'Eindhoven'};
            app.CityDropDown.Value = 'Aruba';
            app.LocationTypeDropDownLabel = uilabel(app.UIFigure);
            app.LocationTypeDropDownLabel.Text = 'Location Type';
            app.LocationTypeDropDown = uidropdown(app.UIFigure);
            app.LocationTypeDropDown.Items = {'Coastal Open', 'Coastal Urban', 'Airport', 'Inland Open', 'Inland Urban', 'Large Cities'};
            app.LocationTypeDropDown.Value = 'Coastal Open';
            app.calculate = uibutton(app.UIFigure, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculatePushed, true);
            app.calculate.Text = 'Gimme Power !!!!';
            app.TurbineDropDownLabel = uilabel(app.UIFigure);
            app.TurbineDropDownLabel.Text = 'Turbine';
            app.TurbineDropDown = uidropdown(app.UIFigure);
            app.TurbineDropDown.Items = {'HiVAWT 3kW'};
            app.TurbineDropDown.Value = 'HiVAWT 3kW';
            app.HeightDropDownLabel = uilabel(app.UIFigure);
            app.HeightDropDownLabel.Text = 'Height';
            app.HeightDropDown = uidropdown(app.UIFigure);
            app.HeightDropDown.Items = {'10', '20', '30', '40'};
            app.HeightDropDown.Value = '10';
            app.MonthlyEnergy = uiaxes(app.UIFigure);
            title(app.MonthlyEnergy, 'Monthly Power Output');
            xlabel(app.MonthlyEnergy, 'Months');
            ylabel(app.MonthlyEnergy, 'Power (kWh)');
            app.PowerNEST20Label = uilabel(app.UIFigure);
            app.PowerNEST20Label.Text = 'PowerNEST 2.0';
            app.BuildingLengthEditFieldLabel = uilabel(app.UIFigure);
            app.BuildingLengthEditFieldLabel.Text = 'Building Length';
            app.BuildingLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuildingLengthEditField.ValueChangedFcn = createCallbackFcn(app, @calcNumberofTurbines, true);
            app.BuildingLengthEditField.Limits = [0 Inf];
            app.BuildingLengthEditField.Value = 10.8;
            app.TurbineOrientationDropDownLabel = uilabel(app.UIFigure);
            app.TurbineOrientationDropDownLabel.Text = 'Turbine Orientation';
            app.TurbineOrientationDropDown = uidropdown(app.UIFigure);
            app.TurbineOrientationDropDown.Items = {'N', 'NNE', 'NE', 'ENE', 'E', 'ESE', 'SE', 'SSE', 'S', 'SSW', 'SW', 'WSW', 'W', 'WNW', 'NW', 'NNW'};
            app.TurbineOrientationDropDown.Value = 'N';
            app.IdealTurbineOrientationEditFieldLabel = uilabel(app.UIFigure);
            app.IdealTurbineOrientationEditFieldLabel.Text = 'Ideal Turbine Orientation';
            app.IdealTurbineOrientationEditField = uieditfield(app.UIFigure, 'text');
            app.IdealTurbineOrientationEditField.Editable = 'off';
            app.BuildingDepthEditField_2Label = uilabel(app.UIFigure);
            app.BuildingDepthEditField_2Label.Text = 'Building Depth';
            app.BuildingDepthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BuildingDepthEditField_2.Limits = [0 Inf];
            app.BuildingDepthEditField_2.Value = 7.2;
            app.NumberofTurbinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTurbinesEditFieldLabel.Text = 'Number of Turbines';
            app.NumberofTurbinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTurbinesEditField.Editable = 'off';
            app.NumberofTurbinesEditField.Value = 1;
            app.WindComponentLabel = uilabel(app.UIFigure);
            app.WindComponentLabel.Text = 'Wind Component';
            app.SolarComponentLabel = uilabel(app.UIFigure);
            app.SolarComponentLabel.Text = 'Solar Component';
            app.PossibleSideEdgesDropDownLabel = uilabel(app.UIFigure);
            app.PossibleSideEdgesDropDownLabel.Text = 'Possible Side Edges';
            app.PossibleSideEdgesDropDown = uidropdown(app.UIFigure);
            app.PossibleSideEdgesDropDown.Items = {'0', '1', '2'};
            app.PossibleSideEdgesDropDown.Value = '0';
            app.FrontandBackEdgesDropDownLabel = uilabel(app.UIFigure);
            app.FrontandBackEdgesDropDownLabel.Text = 'Front and Back Edges';
            app.FrontandBackEdgesDropDown = uidropdown(app.UIFigure);
            app.FrontandBackEdgesDropDown.Items = {'0', '1', '2'};
            app.FrontandBackEdgesDropDown.Value = '0';
            app.NearestCityDropDownLabel = uilabel(app.UIFigure);
            app.NearestCityDropDownLabel.Text = 'Nearest City';
            app.NearestCityDropDown = uidropdown(app.UIFigure);
            app.NearestCityDropDown.Items = {'Almere Stad', 'Aruba'};
            app.NearestCityDropDown.Value = 'Almere Stad';
            app.OutputsTextAreaLabel = uilabel(app.UIFigure);
            app.OutputsTextAreaLabel.Text = 'Outputs ';
            app.OutputsTextArea = uitextarea(app.UIFigure);
            app.SystemConfigurationTextArea = uitextarea(app.UIFigure);
            app.SystemConfigurationTextArea.Editable = 'off';
            app.SystemConfigurationLabel = uilabel(app.UIFigure);
            app.SystemConfigurationLabel.Text = 'System Configuration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PowerCalculation()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end